classdef dialog_LUT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        setupLUTUIFigure               matlab.ui.Figure
        BackgroundDropDown             matlab.ui.control.DropDown
        BackgroundDropDownLabel        matlab.ui.control.Label
        ReverseCheckBox                matlab.ui.control.CheckBox
        ColormapLimitminmaxSlider      matlab.ui.control.RangeSlider
        ColormapLimitLabel             matlab.ui.control.Label
        ApplyColormaptoFiguresListBox  matlab.ui.control.ListBox
        ApplyColormaptoFiguresListBoxLabel  matlab.ui.control.Label
        ApplyColormapButton            matlab.ui.control.Button
        ColorbyvalueDropDown           matlab.ui.control.DropDown
        ColorbyvalueLabel              matlab.ui.control.Label
        ColormapLUTDropDown            matlab.ui.control.DropDown
        ColormapLUTLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        skip_tags = ["dialog_LUT", "dialog_preference", "dialog_filter", "figure_attribute", "figure_histogram"];
        window_all
        hImg
        % lut_classes = ["matlab.graphics.chart.primitive.Line", ...
        %     "matlab.graphics.chart.primitive.Histogram", ...
        %     "matlab.graphics.chart.primitive.Scatter", ...
        %     "matlab.graphics.primitive.Image"];
        %lut_classes = ['scatter', 'image']; %'histogram', 'line',
    end
    
    properties (Access = public)
        Tag = "dialog_LUT"; % Description % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            app.CallingApp = mainapp;
            if length(app.CallingApp.children_apps) <= 1
                return;
            end

            app.window_all = app.CallingApp.children_apps; %findall(groot(), 'Type', 'figure');

            % is_uifig = false(numel(fig_all), 1);
            % for ii = 1:numel(fig_all)
            %     is_uifig(ii) = matlab.ui.internal.isUIFigure(fig_all(ii));
            % end
            %fig_all = fig_all(is_uifig);
            %names_window = cell(length(app.window_all), 1);
            tags_window = cellfun(@(window) window.Tag, app.window_all, 'uni', 0);
            for i = length(tags_window) : -1 : 1
                if any(startsWith(tags_window{i}, app.skip_tags))
                    app.window_all(i) = [];
                    tags_window(i) = [];
                else
                    tags_window{i} = app.window_all{i}.Name;
                end
                %if names_fig{i}
            end
            app.ApplyColormaptoFiguresListBox.Items = tags_window;
            
            try
                items = app.ColorbyvalueDropDown.Items;
                app.ColorbyvalueDropDown.Items = [items; app.CallingApp.data.prop.attr_names];
            catch
            end

            % add context menu to colormap limit range slider
            cm = uicontextmenu(app.setupLUTUIFigure);
            uimenu(cm, "Text", "setup range", "MenuSelectedFcn", @setup_range);
            app.ColormapLimitminmaxSlider.ContextMenu = cm;
            function setup_range (~, ~)
                value = app.ColormapLimitminmaxSlider.Limits;
                prompt = {'min value:', 'max value:'};
                dlgtitle = 'Setup Colormap Range Limits';
                fieldsize = [1 25; 1 25];
                definput = {string(value(1)), string(value(2))};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                if isempty(answer)
                    return;
                end
                value(1) = str2double(answer{1});
                value(2) = str2double(answer{2});
                app.ColormapLimitminmaxSlider.Limits = value;
            end

            g = fspecial("gaussian", [512, 512], 128); % Create Gaussian matrix.
            g = g * 100 / max(g(:));
            app.hImg = imshow( imresize(g, [256 128]), [], 'Parent', app.UIAxes);
            axis( app.UIAxes, 'image' );
            colorbar( app.UIAxes );
            colormap( app.UIAxes, 'default' );
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';

        end

        % Value changed function: ColormapLUTDropDown
        function ColormapLUTDropDownValueChanged(app, event)
            value = app.ColormapLUTDropDown.Value;
            if strcmp(value, 'glasbey')
                glasbey_lut = make_glasbey();
                colormap( app.UIAxes, glasbey_lut );
            else
                colormap( app.UIAxes, value );
            end
            app.UIAxes.Title.String = string(value);
            app.ReverseCheckBox.Value = 0;
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            set(app.UIAxes,'Color', app.BackgroundDropDown.Value);
        end

        % Value changed function: ReverseCheckBox
        function ReverseCheckBoxValueChanged(app, event)
            cmap = colormap( app.UIAxes );
            colormap( app.UIAxes, flipud(cmap) );
        end

        % Value changed function: ColormapLimitminmaxSlider
        function ColormapLimitminmaxSliderValueChanged(app, event)
            value = app.ColormapLimitminmaxSlider.Value;
            clim( app.UIAxes, value );
        end

        % Value changed function: ColorbyvalueDropDown
        function ColorbyvalueDropDownValueChanged(app, event)
            if isequal(app.ColorbyvalueDropDown.Value, "auto")
                app.ColormapLimitminmaxSlider.Limits = [0, 100];
                app.ColormapLimitminmaxSlider.Value = [0, 100];
                app.hImg.CData = rescale(app.hImg.CData, 0, 100);
                app.UIAxes.CLim = [0, 100];
                return;
            end
            try
                val = double(app.CallingApp.data.attr.(app.ColorbyvalueDropDown.Value));
                if ~isnan(range(val)) && range(val) ~= 0
                    app.ColormapLimitminmaxSlider.Limits = [min(val), max(val)];
                    app.ColormapLimitminmaxSlider.Value = [min(val), max(val)];
                    app.hImg.CData = rescale(app.hImg.CData, min(val), max(val));
                    app.UIAxes.CLim = [min(val), max(val)];
                end
            catch
            end
        end

        % Double-clicked callback: ApplyColormaptoFiguresListBox
        function ApplyColormaptoFiguresListBoxDoubleClicked(app, event)
            idx_selected = event.InteractionInformation.Item;
            fig = app.window_all(idx_selected);%findobj('Type', 'Figure', 'Name', name_selected);
            if iscell(fig)
                fig = fig{1};
            end
            try
                ax = findall(fig, 'type', 'axes'); % assume only one plot axes in figure
                cmap_selected = colormap( ax );
                %clim_selected = ax.CLim;
                %app.ColormapLimitminmaxSlider.Limits = double(clim_selected);
                %app.ColormapLimitminmaxSlider.Value = double(clim_selected);
                %app.UIAxes.CLim = clim_selected;
                app.UIAxes.Colormap = cmap_selected;
            catch
            end
        end

        % Button pushed function: ApplyColormapButton
        function ApplyColormapButtonPushed(app, event)
            name_selected = app.ApplyColormaptoFiguresListBox.Value;
            name_check = cellfun(@(x) isequal(x.Name, name_selected), app.window_all, 'uni', 0);
            idx = find([name_check{:}]);
            if idx == 0 
                return;
            end
            fig = app.window_all{idx};
            if iscell(fig)
                fig = fig{1};
            end
            if isempty(fig)
                return;
            end
            % get current colormap: name, fliped? limit?
            cmap = app.UIAxes.Colormap;
            clim = app.UIAxes.CLim;
            ax = findobj(fig, 'Type', 'Axes');
            ax.Colormap = cmap;
            ax.CLim = clim;
            ax.Color = app.BackgroundDropDown.Value;
            
            cval = app.ColorbyvalueDropDown.Value;
            if isequal(cval, 'auto')
                return;
            end
            
            cdata = app.CallingApp.data.attr.(cval);%(ftr, :);

            switch fig.Tag

                case "figure_scatter"
                    scatter_plot = findobj(fig, 'Type', 'scatter');
                    scatter_plot.CData = cdata;
                    cb = findobj(fig, 'Type', 'colorbar');
                    cb.Label.String = cval;

                case "figure_render"
                    image_handle = findobj(fig, 'Type', 'image');
                    image_handle.CData = cdata;
            end
            

        end

        % Close request function: setupLUTUIFigure
        function setupLUTUIFigureCloseRequest(app, event)
            idx = find( cellfun(@(x) isequal(x, app), app.CallingApp.children_apps) );
            app.CallingApp.children_apps(idx) = []; 
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.setupLUTUIFigure = uifigure('Visible', 'off');
            app.setupLUTUIFigure.Name = 'setup LUT';
            app.setupLUTUIFigure.CloseRequestFcn = createCallbackFcn(app, @setupLUTUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.setupLUTUIFigure);
            title(app.UIAxes, 'Colormap Preview')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.ColormapLUTLabel = uilabel(app.setupLUTUIFigure);
            app.ColormapLUTLabel.Text = 'Colormap (LUT ):';
            app.ColormapLUTDropDown = uidropdown(app.setupLUTUIFigure);
            app.ColormapLUTDropDown.Items = {'default', 'hot', 'gray', 'glasbey', 'jet', 'parula', 'turbo', 'hsv', 'bone', 'copper', 'pink', 'white', 'flag', 'lines', 'colorcube', 'vga', 'prism', 'cool', 'autumn', 'spring', 'winter', 'summer'};
            app.ColormapLUTDropDown.Editable = 'on';
            app.ColormapLUTDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapLUTDropDownValueChanged, true);
            app.ColormapLUTDropDown.Value = 'default';
            app.ColorbyvalueLabel = uilabel(app.setupLUTUIFigure);
            app.ColorbyvalueLabel.Text = 'Color by  value :';
            app.ColorbyvalueDropDown = uidropdown(app.setupLUTUIFigure);
            app.ColorbyvalueDropDown.Items = {'auto'};
            app.ColorbyvalueDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbyvalueDropDownValueChanged, true);
            app.ColorbyvalueDropDown.Value = 'auto';
            app.ApplyColormapButton = uibutton(app.setupLUTUIFigure, 'push');
            app.ApplyColormapButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyColormapButtonPushed, true);
            app.ApplyColormapButton.Text = 'Apply Colormap';
            app.ApplyColormaptoFiguresListBoxLabel = uilabel(app.setupLUTUIFigure);
            app.ApplyColormaptoFiguresListBoxLabel.Text = 'Apply Colormap to Figure(s)';
            app.ApplyColormaptoFiguresListBox = uilistbox(app.setupLUTUIFigure);
            app.ApplyColormaptoFiguresListBox.Items = {};
            app.ApplyColormaptoFiguresListBox.DoubleClickedFcn = createCallbackFcn(app, @ApplyColormaptoFiguresListBoxDoubleClicked, true);
            app.ApplyColormaptoFiguresListBox.Value = {};
            app.ColormapLimitLabel = uilabel(app.setupLUTUIFigure);
            app.ColormapLimitLabel.Text = 'Colormap Limit :    min   -   max';
            app.ColormapLimitminmaxSlider = uislider(app.setupLUTUIFigure, 'range');
            app.ColormapLimitminmaxSlider.Limits = [0 256];
            app.ColormapLimitminmaxSlider.ValueChangedFcn = createCallbackFcn(app, @ColormapLimitminmaxSliderValueChanged, true);
            app.ColormapLimitminmaxSlider.Tooltip = {''};
            app.ColormapLimitminmaxSlider.Value = [0 256];
            app.ReverseCheckBox = uicheckbox(app.setupLUTUIFigure);
            app.ReverseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseCheckBoxValueChanged, true);
            app.ReverseCheckBox.Text = 'Reverse';
            app.BackgroundDropDownLabel = uilabel(app.setupLUTUIFigure);
            app.BackgroundDropDownLabel.Text = 'Background :';
            app.BackgroundDropDown = uidropdown(app.setupLUTUIFigure);
            app.BackgroundDropDown.Items = {'white', 'black'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = 'white';
            % Show the figure after all components are created
            app.setupLUTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_LUT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.setupLUTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.setupLUTUIFigure)
        end
    end
end