classdef dialog_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AttributeFilterUIFigure  matlab.ui.Figure
        loadFilterButton         matlab.ui.control.Button
        saveFilterButton         matlab.ui.control.Button
        addFilterButton          matlab.ui.control.Button
        deleteFilterButton       matlab.ui.control.Button
        displayFilterButton      matlab.ui.control.Button
        UITableFilter            matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % Description
        fig_histogram
        filter_idx_selected
        min_line % Description
        max_line
    end
    
    properties (Access = public)
        Tag = "dialog_filter"; % Description
    end
    
    methods (Access = private)
        
        function filter_to_UI (app, filters)

            if isempty(filters) || ~isfield(filters, 'filter') || isempty(filters.filter)
                return
            end
            for i = 1 : size(filters.filter, 1)
                filter = filters.filter(i, 1:5);
                %app.UITableFilter.Data(end+1, :) = filter(1:5);
        
                if isempty(app.UITableFilter.Data)
                    app.UITableFilter.Data = filter;
                else
                    app.UITableFilter.Data(end+1, :) = filter;
                end
            end
        
        end


        function find_histogram_figure (app)
            window_all = app.CallingApp.children_apps;
            try
                for i = 1 : length(window_all)
                    if ~isvalid(window_all{i})
                        continue;
                    end
                    if isequal(window_all{i}.Tag, "figure_histogram")
                        app.fig_histogram = window_all{i};
                        break;
                    end
                end
            catch
            end

            if isempty(app.fig_histogram)
                app.fig_histogram = plot_histogram (app.CallingApp);
                app.CallingApp.children_apps{end+1} = app.fig_histogram;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            % add drag and drop functionality to UI
            enable_drag_and_drop (app.AttributeFilterUIFigure, @file_dropped);
            %load Preferences
            %app.Prefs = load_prefs();
            
            if isfield(app.CallingApp.data, "filters")
                filter_to_UI (app, app.CallingApp.data.filters);
            end

            %call-back function to handel drag and drop event
            function file_dropped (~, evt)
                parse_file_path ( app.CallingApp, evt.names{1} ); % contains load_filter function
                filter_to_UI (app, app.CallingApp.data.filters);
            end

        end

        % Button pushed function: loadFilterButton
        function loadFilterButtonPushed(app, event)
            %last_path = getpref('mfx_viewer', 'default_folder', "");
            %if isempty(last_path)
                [file, folder] = uigetfile('*.json', "select filter .json file", app.CallingApp.data.file.folder);
            %else
            %    [file, folder] = uigetfile('*.mat', "select MINFLUX raw data .mat file", last_path);
            %end


            load_filter (app.CallingApp, file, folder);
            filter_to_UI (app, app.CallingApp.data.filters);
        end

        % Button pushed function: saveFilterButton
        function saveFilterButtonPushed(app, event)
            if isempty(app.UITableFilter.Data) || isempty(app.CallingApp.data.filters)
                return;
            end
            
            file = app.CallingApp.data.file;
            [~, name, ~] = fileparts(file.name);

            [save_file, save_path, indx] = uiputfile(fullfile(file.folder, strcat(name, '_filter.json')), 'save filter as json file');
            if indx == 0
                return;
            end

            header = ["apply", "attribute", "value_as", "min", "max"];
            jsonData = cell2struct(app.UITableFilter.Data, header, 2);
            jsonText = jsonencode(jsonData, 'PrettyPrint', true);

            fid = fopen(fullfile(save_path, save_file), 'w');
            fprintf(fid, '%s', jsonText);
            fclose(fid);

        end

        % Button pushed function: addFilterButton
        function addFilterButtonPushed(app, event)
            % check if two vertical line exist in histogram plot
            % if yes, add min and max value to filter list, and table
            % if not, add two vertical line

        end

        % Button pushed function: deleteFilterButton
        function deleteFilterButtonPushed(app, event)

            if isempty(app.UITableFilter.Data) || isempty(app.CallingApp.data.filters) || isempty(app.filter_idx_selected)
                return;
            end
            %tdata = app.UITableFilter.Data;
            app.UITableFilter.Data(app.filter_idx_selected, :) = [];
            %app.UITableFilter.Data = tdata;
            app.CallingApp.data.filters(app.filter_idx_selected, :) = [];

        end

        % Button pushed function: displayFilterButton
        function displayFilterButtonPushed(app, event)
            % get selected filter in table
            if isempty(app.UITableFilter.Data) || isempty(app.filter_idx_selected)
                return;
            end
            % locate histogram plot window
            if isempty(app.fig_histogram)
                find_histogram_figure (app);
            end

            % display selected filter
            app_data = app.CallingApp.data;
            filter = app_data.filters.filter;
           

            ax = findobj(app.fig_histogram, 'Type', 'Axes');
            attr_list = findobj(app.fig_histogram, 'Tag', 'attr_list');
            var_type = findobj(app.fig_histogram, 'Tag', 'var_type');
            hist_plot = findobj(app.fig_histogram, 'Type', 'histogram');

            attr_list.Value = filter{app.filter_idx_selected, 2};
            var_type.Value = filter{app.filter_idx_selected, 3};
            minVal = filter{app.filter_idx_selected, 4};
            maxVal = filter{app.filter_idx_selected, 5};
            %hist.Data = filter{app.filter_idx_selected, 6};
            
            % compute histogram data and value
            hist_plot.Data = app_data.attr.(attr_list.Value);

            xlabel(ax, strcat(attr_list.Value, " : ", var_type.Value));
            hist_plot.BinMethod = 'auto';
            ax.XLimMode = 'manual';
            ax.XLim = [minVal, maxVal];
            ax.YLimMode = 'auto';


            %app.min_line = xline(ax, minVal, 'LineWidth', 2, 'Color', 'red', 'buttonDownFcn', @startDragFcn);
            cm = uicontextmenu(app.fig_histogram);

            app.min_line = images.roi.Line( ax, ...
                'Position',[minVal minVal; ylim(ax)]', ... % start at x=20
                'Label','min', ...
                'LabelAlpha', .35, ...
                'InteractionsAllowed','translate', ...
                'LineWidth', 2, ...
                'MarkerSize',10, ...
                'Color', [0.35, 0, 0], ...
                'SelectedColor', [1, 0, 0], ...
                'StripeColor','none');
            % Update title when line is moving
            addlistener(app.min_line, 'MovingROI', @lineMovingFcn)
            uimenu(cm, "Text", "set value", "MenuSelectedFcn", @set_value);
            app.min_line.ContextMenu = cm;

            
            app.max_line = images.roi.Line( ax, ...
                'Position',[maxVal maxVal; ylim(ax)]', ... % start at x=20
                'Label','max', ...
                'LabelAlpha', .35, ...
                'InteractionsAllowed','translate', ...
                'LineWidth', 2, ...
                'MarkerSize',10, ...
                'Color', [0.35, 0, 0], ...
                'SelectedColor', [1, 0, 0], ...
                'StripeColor','none');
            % Update title when line is moving
            addlistener(app.max_line, 'MovingROI', @lineMovingFcn)
            app.max_line.ContextMenu = cm;


            %app.max_line = xline(ax, maxVal, 'LineWidth', 2, 'Color', 'red', 'buttonDownFcn', @startDragFcn);
            
            function lineMovingFcn(obj,event)
                % Update title with new x position of the line
                ax = ancestor(obj, 'axes');
                ax.Title.String = sprintf('x = %.3f', event.CurrentPosition(1));
            end
            function set_value (src, evt)
                prompt = {'minimum value', 'maximum value'};
                title = 'set filter value (inclusive)';
                dims = [1, 55];
                definput = { num2str(app.min_line.Position(1)), num2str(app.max_line.Position(1)) };
                answer = inputdlg(prompt, title, dims, definput);
                if isempty(answer)
                    return;
                end
                app.min_line.Position(1) = str2double(answer{1});
                app.min_line.Position(2) = str2double(answer{1});
                app.max_line.Position(1) = str2double(answer{2});
                app.max_line.Position(2) = str2double(answer{2});
            end

            function startDragFcn(src, ~)
                set(app.fig_histogram, 'WindowButtonMotionFcn', {@draggingFcn, src});
            end
            function draggingFcn(~, ~, src_line)
                pt=get(ax, 'CurrentPoint');
                newX = pt(1,1);
                src_line.Value = newX;
            end

        end

        % Cell selection callback: UITableFilter
        function UITableFilterCellSelection(app, event)
            app.filter_idx_selected = event.Indices(:, 1);
        end

        % Cell edit callback: UITableFilter
        function UITableFilterCellEdit(app, event)
            % if no MINFLUX data is loaded, uncheck all apply boxes
            if isempty(app.CallingApp.data)
                for i = 1 : size(app.UITableFilter.Data, 1)
                    app.UITableFilter.Data{i, 1} = false;
                end
                return;
            end
            % if data.filters.ftr not exist, compute the ftr array and update data attribute
            data = app.CallingApp.data;
            if ~isfield(data.filters, 'ftr')
                ftr = true(data.prop.num_loc, 1);
                for i = 1 : size(app.UITableFilter.Data, 1)
                     ftr_row = compute_filter_array (app.CallingApp, app.UITableFilter.Data(i, :));
                     data.filters.filter{i, 6} = ftr_row;
                     if data.filters.filter{i, 1}
                         ftr = ftr & ftr_row;
                     end
                end
                app.CallingApp.data.attr.ftr = ftr;
                app.CallingApp.data.filters.ftr = ftr;
            end

            % check which row in filter table is edited 
            row = event.Indices(1);            
            row_data = app.UITableFilter.Data(row, :);
            % compute and update ftr array for the selected row
            ftr_row = compute_filter_array (app.CallingApp, row_data);
            app.CallingApp.data.filters.filter{row, 6} = ftr_row;

            % update data ftr attribute
            ftr = true(data.prop.num_loc, 1);
            for i = 1 : size(app.UITableFilter.Data, 1)
                 if app.UITableFilter.Data{i, 1}
                     ftr = ftr & data.filters.filter{i, 6};
                 end
            end
            app.CallingApp.data.attr.ftr = ftr;

            % apply filter array to data, in all plots:
            % attribute plot, histogram, scatter plot, render image
            apply_filter_to_data (app.CallingApp);
        end

        % Close request function: AttributeFilterUIFigure
        function AttributeFilterUIFigureCloseRequest(app, event)
            idx = find( cellfun(@(x) isequal(x, app), app.CallingApp.children_apps) );
            app.CallingApp.children_apps(idx) = []; 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AttributeFilterUIFigure = uifigure('Visible', 'off');
            app.AttributeFilterUIFigure.Name = 'Attribute Filter';
            app.AttributeFilterUIFigure.CloseRequestFcn = createCallbackFcn(app, @AttributeFilterUIFigureCloseRequest, true);
            app.UITableFilter = uitable(app.AttributeFilterUIFigure);
            app.UITableFilter.ColumnName = {'apply'; 'attribute'; 'value as'; 'min'; 'max'};
            app.UITableFilter.RowName = {};
            app.UITableFilter.SelectionType = 'row';
            app.UITableFilter.ColumnEditable = [true false false true true];
            app.UITableFilter.CellEditCallback = createCallbackFcn(app, @UITableFilterCellEdit, true);
            app.UITableFilter.CellSelectionCallback = createCallbackFcn(app, @UITableFilterCellSelection, true);
            app.UITableFilter.Multiselect = 'off';
            app.displayFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.displayFilterButton.ButtonPushedFcn = createCallbackFcn(app, @displayFilterButtonPushed, true);
            app.displayFilterButton.Text = 'display';
            app.deleteFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.deleteFilterButton.ButtonPushedFcn = createCallbackFcn(app, @deleteFilterButtonPushed, true);
            app.deleteFilterButton.Text = '-';
            app.addFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.addFilterButton.ButtonPushedFcn = createCallbackFcn(app, @addFilterButtonPushed, true);
            app.addFilterButton.Text = {'+'; ''};
            app.saveFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.saveFilterButton.ButtonPushedFcn = createCallbackFcn(app, @saveFilterButtonPushed, true);
            app.saveFilterButton.Text = 'save';
            app.loadFilterButton = uibutton(app.AttributeFilterUIFigure, 'push');
            app.loadFilterButton.ButtonPushedFcn = createCallbackFcn(app, @loadFilterButtonPushed, true);
            app.loadFilterButton.Text = 'load';
            % Show the figure after all components are created
            app.AttributeFilterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_filter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AttributeFilterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AttributeFilterUIFigure)
        end
    end
end