classdef dialog_preference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreferencesUIFigure            matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FileTab                        matlab.ui.container.Tab
        FilesinhistorySpinner          matlab.ui.control.Spinner
        FilesinhistorySpinnerLabel     matlab.ui.control.Label
        DataTab                        matlab.ui.container.Tab
        LoadIterationDropDownLabel_14  matlab.ui.control.Label
        ofEditField                    matlab.ui.control.NumericEditField
        localdensitywithinradiusCheckBox  matlab.ui.control.CheckBox
        allDCRiterationCheckBox        matlab.ui.control.CheckBox
        LoadIterDropDown               matlab.ui.control.DropDown
        LoadDropDownLabel              matlab.ui.control.Label
        effectiveCFRandEFCiterationCheckBox  matlab.ui.control.CheckBox
        datainfoCheckBox               matlab.ui.control.CheckBox
        LocalizationPrecisionCheckBox  matlab.ui.control.CheckBox
        RIMFvalueZscalingfactorCheckBox  matlab.ui.control.CheckBox
        LoadIterationDropDownLabel_13  matlab.ui.control.Label
        LoadIterationDropDownLabel_12  matlab.ui.control.Label
        LoadIterationDropDownLabel_11  matlab.ui.control.Label
        scatterplotCheckBox            matlab.ui.control.CheckBox
        WhenopeningdatafileLabel       matlab.ui.control.Label
        renderviewCheckBox             matlab.ui.control.CheckBox
        histogramplotCheckBox          matlab.ui.control.CheckBox
        attributeplotCheckBox          matlab.ui.control.CheckBox
        PlotTab                        matlab.ui.container.Tab
        renderpixelsizeSpinner         matlab.ui.control.Spinner
        renderpixelsizeSpinnerLabel    matlab.ui.control.Label
        RIMFSpinner                    matlab.ui.control.Spinner
        RIMFSpinnerLabel               matlab.ui.control.Label
        rendercolormapDropDown_2       matlab.ui.control.DropDown
        rendercolormapDropDown_2Label  matlab.ui.control.Label
        PlotcolormapDropDown_2         matlab.ui.control.DropDown
        PlotcolormapDropDown_2Label    matlab.ui.control.Label
        ROIcolorDropDown_2             matlab.ui.control.DropDown
        ROIcolorDropDown_2Label        matlab.ui.control.Label
        LoadIterationDropDownLabel_10  matlab.ui.control.Label
        DefaultparametersLabel_2       matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        Prefs % Description
    end
    
    properties (Access = public)
        Tag = "dialog_preference"; % Description % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.Prefs = load_prefs ();
            
            % Update UI with input values
            app.LoadIterDropDown.Value = app.Prefs.on_data_load.iter;
            app.attributeplotCheckBox.Value = app.Prefs.on_data_load.plot_attr;
            app.scatterplotCheckBox.Value = app.Prefs.on_data_load.plot_scatter;
            app.histogramplotCheckBox.Value = app.Prefs.on_data_load.plot_hist;
            app.renderviewCheckBox.Value = app.Prefs.on_data_load.render_image;
            
            app.RimfEditField.Value = app.Prefs.RIMF;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            % on_data_load = struct;
            % 
            % on_data_load.iter = app.LoadIterDropDown.Value;
            % on_data_load.plot_attr = app.attributeplotCheckBox.Value;
            % on_data_load.plot_scatter = app.scatterplotCheckBox.Value;
            % on_data_load.plot_hist = app.histogramplotCheckBox.Value;
            % on_data_load.render_image = app.renderviewCheckBox.Value;
            % 
            % app.Prefs.on_data_load = on_data_load;
            % 
            % app.Prefs.RIMF = app.RimfEditField.Value;
            % 
            % save_prefs (app.Prefs);
            
            delete(app);  
        end

        % Close request function: PreferencesUIFigure
        function PreferencesUIFigureCloseRequest(app, event)
            idx = find( cellfun(@(x) isequal(x, app), app.CallingApp.children_apps) );
            app.CallingApp.children_apps(idx) = []; 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreferencesUIFigure = uifigure('Visible', 'off');
            app.PreferencesUIFigure.Name = 'Preferences';
            app.PreferencesUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreferencesUIFigureCloseRequest, true);
            app.OKButton = uibutton(app.PreferencesUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TabGroup = uitabgroup(app.PreferencesUIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.FilesinhistorySpinnerLabel = uilabel(app.FileTab);
            app.FilesinhistorySpinnerLabel.Text = 'Files in history';
            app.FilesinhistorySpinner = uispinner(app.FileTab);
            app.FilesinhistorySpinner.Limits = [0 50];
            app.FilesinhistorySpinner.Value = 5;
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.attributeplotCheckBox = uicheckbox(app.DataTab);
            app.attributeplotCheckBox.Text = 'attribute plot';
            app.attributeplotCheckBox.Value = true;
            app.histogramplotCheckBox = uicheckbox(app.DataTab);
            app.histogramplotCheckBox.Text = 'histogram plot';
            app.renderviewCheckBox = uicheckbox(app.DataTab);
            app.renderviewCheckBox.Text = 'render view ';
            app.WhenopeningdatafileLabel = uilabel(app.DataTab);
            app.WhenopeningdatafileLabel.Text = 'When opening data file: ';
            app.scatterplotCheckBox = uicheckbox(app.DataTab);
            app.scatterplotCheckBox.Text = 'scatter plot';
            app.LoadIterationDropDownLabel_11 = uilabel(app.DataTab);
            app.LoadIterationDropDownLabel_11.Text = 'iteration';
            app.LoadIterationDropDownLabel_12 = uilabel(app.DataTab);
            app.LoadIterationDropDownLabel_12.Text = 'Show:';
            app.LoadIterationDropDownLabel_13 = uilabel(app.DataTab);
            app.LoadIterationDropDownLabel_13.Text = 'Compute:';
            app.RIMFvalueZscalingfactorCheckBox = uicheckbox(app.DataTab);
            app.RIMFvalueZscalingfactorCheckBox.Text = 'RIMF value (Z scaling factor)';
            app.LocalizationPrecisionCheckBox = uicheckbox(app.DataTab);
            app.LocalizationPrecisionCheckBox.Text = 'Localization Precision';
            app.datainfoCheckBox = uicheckbox(app.DataTab);
            app.datainfoCheckBox.Text = 'data info...';
            app.effectiveCFRandEFCiterationCheckBox = uicheckbox(app.DataTab);
            app.effectiveCFRandEFCiterationCheckBox.Text = 'effective CFR and EFC iteration';
            app.effectiveCFRandEFCiterationCheckBox.Value = true;
            app.LoadDropDownLabel = uilabel(app.DataTab);
            app.LoadDropDownLabel.Text = 'Load';
            app.LoadIterDropDown = uidropdown(app.DataTab);
            app.LoadIterDropDown.Items = {'last', 'all'};
            app.LoadIterDropDown.Value = 'last';
            app.allDCRiterationCheckBox = uicheckbox(app.DataTab);
            app.allDCRiterationCheckBox.Text = 'all DCR iteration';
            app.localdensitywithinradiusCheckBox = uicheckbox(app.DataTab);
            app.localdensitywithinradiusCheckBox.Text = 'local density within radius';
            app.ofEditField = uieditfield(app.DataTab, 'numeric');
            app.ofEditField.Limits = [0 Inf];
            app.ofEditField.Value = 100;
            app.LoadIterationDropDownLabel_14 = uilabel(app.DataTab);
            app.LoadIterationDropDownLabel_14.Text = 'nm';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.DefaultparametersLabel_2 = uilabel(app.PlotTab);
            app.DefaultparametersLabel_2.Text = 'Default parameters;';
            app.LoadIterationDropDownLabel_10 = uilabel(app.PlotTab);
            app.LoadIterationDropDownLabel_10.Text = 'nm';
            app.ROIcolorDropDown_2Label = uilabel(app.PlotTab);
            app.ROIcolorDropDown_2Label.Text = 'ROI color';
            app.ROIcolorDropDown_2 = uidropdown(app.PlotTab);
            app.ROIcolorDropDown_2.Items = {'Yellow', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Custom'};
            app.ROIcolorDropDown_2.Value = 'Yellow';
            app.PlotcolormapDropDown_2Label = uilabel(app.PlotTab);
            app.PlotcolormapDropDown_2Label.Text = 'Plot colormap';
            app.PlotcolormapDropDown_2 = uidropdown(app.PlotTab);
            app.PlotcolormapDropDown_2.Items = {'single color', 'Parula', 'Jet', 'Glasbey'};
            app.PlotcolormapDropDown_2.Value = 'single color';
            app.rendercolormapDropDown_2Label = uilabel(app.PlotTab);
            app.rendercolormapDropDown_2Label.Text = 'render colormap';
            app.rendercolormapDropDown_2 = uidropdown(app.PlotTab);
            app.rendercolormapDropDown_2.Items = {'Hot', 'Parula', 'Jet', 'Glasbey'};
            app.rendercolormapDropDown_2.Value = 'Hot';
            app.RIMFSpinnerLabel = uilabel(app.PlotTab);
            app.RIMFSpinnerLabel.Text = 'RIMF';
            app.RIMFSpinner = uispinner(app.PlotTab);
            app.RIMFSpinner.Step = 0.001;
            app.RIMFSpinner.Limits = [0 1];
            app.RIMFSpinner.Value = 0.67;
            app.renderpixelsizeSpinnerLabel = uilabel(app.PlotTab);
            app.renderpixelsizeSpinnerLabel.Text = 'render pixel size';
            app.renderpixelsizeSpinner = uispinner(app.PlotTab);
            app.renderpixelsizeSpinner.Limits = [0 Inf];
            app.renderpixelsizeSpinner.Value = 2;
            % Show the figure after all components are created
            app.PreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_preference(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreferencesUIFigure)
        end
    end
end